classdef TTP_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ComponentManagerTab             matlab.ui.container.Tab
        dataType                        matlab.ui.container.TabGroup
        RequiredTab                     matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        ToDataScreeningButton           matlab.ui.control.Button
        CASisusedAsauniqueidentifiertolinkdatafromLabel_2  matlab.ui.control.Label
        ImportDataButton                matlab.ui.control.Button
        ClearTableButton                matlab.ui.control.Button
        SaveTableButton                 matlab.ui.control.Button
        AddDataButton                   matlab.ui.control.Button
        ManualDataEntryWindowTextArea   matlab.ui.control.TextArea
        ManualDataEntryWindowTextAreaLabel  matlab.ui.control.Label
        VariableSelectorButtonGroup     matlab.ui.container.ButtonGroup
        CASButton                       matlab.ui.control.ToggleButton
        ComponentButton                 matlab.ui.control.ToggleButton
        cp_J_per_K_molButton            matlab.ui.control.ToggleButton
        cp_temperature_KButton          matlab.ui.control.ToggleButton
        enthalpy_vaporization_kJ_per_molButton  matlab.ui.control.ToggleButton
        enthalpy_vaporization_temperature_KButton  matlab.ui.control.ToggleButton
        saturation_pressure_kPaButton   matlab.ui.control.ToggleButton
        saturation_temperature_KButton  matlab.ui.control.ToggleButton
        criticalPressure_kPaButton      matlab.ui.control.ToggleButton
        criticalTemperature_KButton     matlab.ui.control.ToggleButton
        AllinthissectionisusedforLabel  matlab.ui.control.Label
        CASisusedAsauniqueidentifiertolinkdatafromLabel  matlab.ui.control.Label
        dataDisplayTable                matlab.ui.control.Table
        DataScreeningTab                matlab.ui.container.Tab
        ScreenedDataLabel               matlab.ui.control.Label
        SourceDataLabel                 matlab.ui.control.Label
        table_sourceData                matlab.ui.control.Table
        table_screeningData             matlab.ui.control.Table
        ScreeningProcessTab             matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        StartScreeningButton            matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
        ScreeningTypeTab                matlab.ui.container.Tab
        AutoViaRegressionTreesButton    matlab.ui.control.Button
        ManualButton                    matlab.ui.control.Button
        ManualTab                       matlab.ui.container.Tab
        UsetheplotbrushtooltomarkupdataLabel  matlab.ui.control.Label
        CompleteManualScreeningButton   matlab.ui.control.Button
        RemoveMarkedOutliersButton      matlab.ui.control.Button
        AutoTab                         matlab.ui.container.Tab
        InputParametersPanel            matlab.ui.container.Panel
        NumberofTreesEditField          matlab.ui.control.NumericEditField
        NumberofTreesEditFieldLabel     matlab.ui.control.Label
        MultiplierfortheIQRFenceEditField  matlab.ui.control.NumericEditField
        MultiplierfortheIQRFenceLabel   matlab.ui.control.Label
        BinningSizeEditField            matlab.ui.control.NumericEditField
        BinningSizeLabel                matlab.ui.control.Label
        CompleteScreeningButton         matlab.ui.control.Button
        RejectOutliersButton            matlab.ui.control.Button
        AcceptOutliersButton            matlab.ui.control.Button
        StartAutoScreeningButton        matlab.ui.control.Button
        ToComponentManagerButton        matlab.ui.control.Button
        PlotAxisPanel                   matlab.ui.container.Panel
        DataTypeDropDown                matlab.ui.control.DropDown
        DataTypeDropDownLabel           matlab.ui.control.Label
        ComponentDropDown               matlab.ui.control.DropDown
        ComponentLabel                  matlab.ui.control.Label
        PlotDataButton                  matlab.ui.control.Button
        YAxisDropDown                   matlab.ui.control.DropDown
        YAxisLabel                      matlab.ui.control.Label
        XAxisDropDown                   matlab.ui.control.DropDown
        XAxisLabel                      matlab.ui.control.Label
        UIAxes_ScreeningData            matlab.ui.control.UIAxes
        OptimizerTab                    matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            % Get the column headers from UITable
            columnHeaders = app.dataDisplayTable.ColumnName;
        
            % Get the selected X and Y axis columns from the dropdowns
            selectedXColumn = app.XAxisDropDown.Value;
            selectedYColumn = app.YAxisDropDown.Value;
        
            % Find the index of the selected columns in the column headers
            xColumnIdx = find(strcmp(columnHeaders, selectedXColumn));
            yColumnIdx = find(strcmp(columnHeaders, selectedYColumn));
        
            % Define the folder path based on the DataTypeDropDown selection
            selectedDataType = app.DataTypeDropDown.Value;
            
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', selectedDataType);
        
            % Get the value selected in the ComponentDropDown (file name)
            selectedFile = app.ComponentDropDown.Value;
        
            % Create the full path to the selected file
            fullFilePath = fullfile(dataFolder, selectedFile);
        
            % Check if the file exists
            if ~exist(fullFilePath, 'file')
                % Display warning if the file does not exist
                uialert(app.UIFigure, 'The selected file could not be found.', 'File Error');
                return;
            end
        
            % Read the data from the file (assuming a CSV format)
            opts = detectImportOptions(fullFilePath);
            tableData = readtable(fullFilePath, opts);
        
            % Get the data from the selected columns for X and Y
            xData = tableData{:, xColumnIdx};
            yData = tableData{:, yColumnIdx};
        
            % Check if the data extracted is numeric
            if ~isnumeric(xData) || ~isnumeric(yData)
                uialert(app.UIFigure, 'The selected columns must contain numeric data for plotting.', 'Plot Error');
                return;
            end
        
            % Check if the two data sets have the same number of rows
            if length(rmmissing(xData)) ~= length(rmmissing(yData))
                uialert(app.UIFigure, 'The selected X and Y data sets must have the same number of rows.', 'Data Size Mismatch');
                return;
            end
        
            % Modify the column names for the labels by replacing "_" with a space
            modifiedXLabel = strrep(selectedXColumn, '_', ' ');
            modifiedYLabel = strrep(selectedYColumn, '_', ' ');
        
            % Plot the data on the UIAxes
            plot(app.UIAxes_ScreeningData, xData, yData, Marker = "o", MarkerSize=8, LineStyle = "none",Color = "#44AA99",LineWidth=1);
        
            % Label the axes
            xlabel(app.UIAxes_ScreeningData, modifiedXLabel);
            ylabel(app.UIAxes_ScreeningData, modifiedYLabel);
            title(app.UIAxes_ScreeningData, sprintf('Plot of %s vs %s', modifiedYLabel, modifiedXLabel));
        
            % Update the screening data table with X and Y data
            % Create tables for X and Y data with appropriate variable names
            xColumnTable = table(xData, 'VariableNames', {modifiedXLabel});
            yColumnTable = table(yData, 'VariableNames', {modifiedYLabel});

            % Check for duplicate variable names before updating the table
            if strcmp(modifiedXLabel, modifiedYLabel)
                uialert(app.UIFigure, 'The X and Y axes have the same name. Please select different columns for X and Y axes.', 'Duplicate Column Names');
                return;
            end            
        
            % Update the screening data tables in app.table_screeningData and app.table_sourceData
            % Ensure that the X-axis data is in the first column and Y-axis data is in the second column
            updatedScreeningData = [xColumnTable, yColumnTable];
            app.table_screeningData.Data = updatedScreeningData;
            app.table_sourceData.Data = updatedScreeningData;

            % Update column headers of the screening tables
            app.table_screeningData.ColumnName = {modifiedXLabel, modifiedYLabel};
            app.table_sourceData.ColumnName = {modifiedXLabel, modifiedYLabel};
        end

        % Button pushed function: StartScreeningButton
        function StartScreeningButtonPushed(app, event)
            
            % Set the selected tab in the TabGroup to ScreeningTab
            app.ScreeningProcessTab.SelectedTab = app.ScreeningTypeTab;

        end

        % Button pushed function: ToDataScreeningButton
        function ToDataScreeningButtonPushed(app, event)
           % Set the selected tab in the TabGroup to ScreeningTab
            app.TabGroup.SelectedTab = app.DataScreeningTab;
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            % Open file selection dialog for CSV files
            [fileName, filePath] = uigetfile('*.csv', 'Select a CSV File to Import');
            
            % Check if the user did not cancel the file selection
            if isequal(fileName, 0)
                % User canceled the file selection
                return;
            end
        
            % Construct the full file path
            fullFilePath = fullfile(filePath, fileName);
        
            % Read the CSV file into a table
            try
                importedTable = readtable(fullFilePath);
            catch
                % Alert the user if there was an error reading the CSV
                uialert(app.UIFigure, 'Failed to read the CSV file. Please make sure it is formatted correctly.', 'Read Error');
                return;
            end
        
            % Convert the imported table to a cell array
            importedData = table2cell(importedTable);
        
            % Update the UITable's data
            app.dataDisplayTable.Data = importedData;
        
            % Inform the user that the CSV file has been imported successfully
            uialert(app.UIFigure, 'Data has been successfully imported from the CSV file.', 'Import Successful');
        end

        % Button pushed function: ClearTableButton
        function ClearTableButtonPushed(app, event)
            % Set the UITable's Data property to an empty cell array to clear the table
            app.dataDisplayTable.Data = {};
        end

        % Button pushed function: AddDataButton
        function AddDataButtonPushed(app, event)
             % Get the data entered by the user
            userInput = app.ManualDataEntryWindowTextArea.Value; % Get input as a string
        
            % Split the user input based on delimiters (commas, new lines, spaces, tabs)
            rows = split(userInput, '\n'); % Split input into rows based on new lines
        
            % Determine if the input has multiple rows and columns
            numRows = length(rows);
            numCols = cellfun(@(x) length(split(x, {',', '\t', ' '})), rows);
        
            % If the number of columns is inconsistent or more than one column and one row, show an error
            if numRows > 1 && any(numCols > 1)
                uialert(app.UIFigure, 'Invalid input format. Please enter either a row or a column vector. Matrix input is not accepted.', 'Input Error');
                return;
            end
        
            % Flatten the input into a single array of entries
            dataEntries = split(userInput, {',', '\n', '\t', ' '});
            dataEntries = strtrim(dataEntries); % Trim any extra whitespace from each entry
            dataEntries(cellfun('isempty', dataEntries)) = []; % Remove empty cells
        
            % Determine if the data is intended to be a number or a string
            selectedButton = app.VariableSelectorButtonGroup.SelectedObject;
        
            % Determine which column to add the data to based on the selected button
            if selectedButton == app.ComponentButton
                colIdx = 1;
                isNumericData = false; % Component name is expected as a string
            elseif selectedButton == app.CASButton
                colIdx = 2;
                isNumericData = false; % CAS is expected as a string (it can be formatted like "50-00-0")
            elseif selectedButton == app.criticalTemperature_KButton
                colIdx = 3;
                isNumericData = true;
            elseif selectedButton == app.criticalPressure_kPaButton
                colIdx = 4;
                isNumericData = true;
            elseif selectedButton == app.saturation_temperature_KButton
                colIdx = 5;
                isNumericData = true;
            elseif selectedButton == app.saturation_pressure_kPaButton
                colIdx = 6;
                isNumericData = true;
            elseif selectedButton == app.enthalpy_vaporization_temperature_KButton
                colIdx = 7;
                isNumericData = true;
            elseif selectedButton == app.enthalpy_vaporization_kJ_per_molButton
                colIdx = 8;
                isNumericData = true;
            elseif selectedButton == app.cp_temperature_KButton
                colIdx = 9;
                isNumericData = true;
            elseif selectedButton == app.cp_J_per_K_molButton
                colIdx = 10;
                isNumericData = true;
            else
                uialert(app.UIFigure, 'Please select a variable to enter data.', 'Selection Error');
                return;
            end
        
            % Validate and convert data entries
            if isNumericData
                % Convert all entries to numeric values
                numericValues = str2double(dataEntries);
                if any(isnan(numericValues))
                    uialert(app.UIFigure, 'Please enter valid numeric values.', 'Input Error');
                    return;
                end
                dataToAdd = num2cell(numericValues); % Convert to cell array for table
            else
                % If the data should be strings, make sure all entries are valid strings
                dataToAdd = cellfun(@char, dataEntries, 'UniformOutput', false); % Convert to cell array of strings
            end
        
            % Prepare new table data, replacing the entire selected column with new data
            currentData = app.dataDisplayTable.Data;
        
            % Ensure that currentData has the correct number of rows
            maxRows = max(length(dataToAdd), size(currentData, 1));
            if isempty(currentData)
                currentData = cell(maxRows, 10); % Initialize with empty cells to fit all columns
            elseif size(currentData, 1) < maxRows
                currentData{maxRows, 10} = []; % Expand to have enough rows
            end
        
            % Replace the selected column with new data
            for i = 1:maxRows
                if i <= length(dataToAdd)
                    currentData{i, colIdx} = dataToAdd{i};
                else
                    currentData{i, colIdx} = []; % Clear old data if the new input is shorter
                end
            end
        
            % Update the table with the new data
            app.dataDisplayTable.Data = currentData;
        
            % Clear the DataEntryTextArea after entering the data
            app.ManualDataEntryWindowTextArea.Value = '';
        end

        % Button pushed function: SaveTableButton
        function SaveTableButtonPushed(app, event)
            % Get the user's "Documents" folder path
            userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            if ~ispc
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            
            % Define the main data folder and the experimental subfolder
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data');
            experimentalFolder = fullfile(dataFolder, 'Experimental_Data');
            
            % Create folders if they don't exist
            if ~exist(dataFolder, 'dir')
                mkdir(dataFolder);
            end
            if ~exist(experimentalFolder, 'dir')
                mkdir(experimentalFolder);
            end
            
            % Extract the Component and CAS Number from the first row of the table
            tableData = app.dataDisplayTable.Data; % Extract the data from the table
            if isempty(tableData) || size(tableData, 1) < 1 || size(tableData, 2) < 2
                uialert(app.UIFigure, 'Table must have at least one row with Component and CAS data.', 'Save Error');
                return;
            end
            
            componentName = tableData{1, 1}; % First row, first column
            casNumber = tableData{1, 2}; % First row, second column
            
            % Validate that both Component and CAS are non-empty strings
            if isempty(componentName) || isempty(casNumber) || ~ischar(componentName) || ~ischar(casNumber)
                uialert(app.UIFigure, 'Component name and CAS number must be non-empty strings in the first row.', 'Save Error');
                return;
            end
            
            % Remove special characters from the Component Name
            modifiedComponentName = regexprep(componentName, '[^a-zA-Z0-9]', '');
            
            % Construct the filename using the modified component name and CAS number
            dataFileName = sprintf('%s_%s.csv', modifiedComponentName, casNumber);
            
            % Define the full path for the .csv file
            dataFilePath = fullfile(experimentalFolder, dataFileName);
            
            % Convert cell array data to a table for easy writing to CSV
            headers = app.dataDisplayTable.ColumnName; % Get the column headers
            tableDataCell = app.dataDisplayTable.Data; % Get the cell array data
            dataTable = cell2table(tableDataCell, 'VariableNames', headers);
            
            % Save the table data to a CSV file
            try
                writetable(dataTable, dataFilePath);
                % Inform the user that data has been saved successfully
                uialert(app.UIFigure, sprintf('Data has been saved successfully as "%s".', dataFileName), 'Save Successful');
            catch ME
                % Inform the user if there was an error saving the data
                uialert(app.UIFigure, sprintf('Failed to save data: %s', ME.message), 'Save Error');
            end

        end

        % Drop down opening function: ComponentDropDown
        function ComponentDropDownOpening(app, event)

            % Get the selected data type from DataTypeDropDown
            selectedDataType = app.DataTypeDropDown.Value;
        
            % Define the folder path for TTP_GUI_Data
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', selectedDataType);
        
            % Check if the specified folder exists
            if ~exist(dataFolder, 'dir')
                % Show an alert if the folder does not exist
                uialert(app.UIFigure, 'The specified data folder could not be found.', 'Folder Not Found');
                % Set ComponentDropDown to default value
                app.ComponentDropDown.Items = {'No Files Available'};
                return;
            end
        
            % Get the list of CSV files in the specified folder
            filePattern = fullfile(dataFolder, '*.csv');
            files = dir(filePattern);
            fileNames = {files.name};
        
            % Update the ComponentDropDown items with the file names
            if isempty(fileNames)
                % If no files are found, provide a default option
                app.ComponentDropDown.Items = {'No Files Available'};
            else
                app.ComponentDropDown.Items = fileNames;
            end


        end

        % Drop down opening function: XAxisDropDown
        function XAxisDropDownOpening(app, event)
            % Get the value selected in the ComponentDropDown (file name)
            selectedFile = app.ComponentDropDown.Value;
        
            % Get the folder specified by DataTypeDropDown
            selectedDataType = app.DataTypeDropDown.Value;
        
            % Define the folder path where the files are stored
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', selectedDataType);
        
            % Create the full path to the selected file
            fullFilePath = fullfile(dataFolder, selectedFile);
        
            % Check if the file exists
            if ~exist(fullFilePath, 'file')
                % Display warning if the file does not exist
                uialert(app.UIFigure, 'The selected file could not be found.', 'File Error');
                return;
            end
        
            % Read the data from the file (assuming a CSV format)
            opts = detectImportOptions(fullFilePath);
            tableData = readtable(fullFilePath, opts);
        
            % Extract column names from the loaded table
            columnHeaders = tableData.Properties.VariableNames;
        
            % Update the SelectionDropDown items with the column headers
            app.XAxisDropDown.Items = columnHeaders;
        end

        % Drop down opening function: YAxisDropDown
        function YAxisDropDownOpening(app, event)
            % Get the value selected in the ComponentDropDown (file name)
            selectedFile = app.ComponentDropDown.Value;
        
            % Get the folder specified by DataTypeDropDown
            selectedDataType = app.DataTypeDropDown.Value;
        
            % Define the folder path where the files are stored
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', selectedDataType);
        
            % Create the full path to the selected file
            fullFilePath = fullfile(dataFolder, selectedFile);
        
            % Check if the file exists
            if ~exist(fullFilePath, 'file')
                % Display warning if the file does not exist
                uialert(app.UIFigure, 'The selected file could not be found.', 'File Error');
                return;
            end
        
            % Read the data from the file (assuming a CSV format)
            opts = detectImportOptions(fullFilePath);
            tableData = readtable(fullFilePath, opts);
        
            % Extract column names from the loaded table
            columnHeaders = tableData.Properties.VariableNames;
        
            % Update the SelectionDropDown items with the column headers
            app.YAxisDropDown.Items = columnHeaders;
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
            % Set the selected tab in the TabGroup to ScreeningTab
            app.ScreeningProcessTab.SelectedTab = app.ManualTab;

        end

        % Button pushed function: RemoveMarkedOutliersButton
        function RemoveMarkedOutliersButtonPushed(app, event)
            % Get the current data in the screening table
            screeningData = app.table_screeningData.Data;
 
            % Get the plotted data from the UIAxes
            plottedData = app.UIAxes_ScreeningData.Children;
        
            % Check if there is only one set of X and Y values in the plot
            if length(plottedData) > 1
                uialert(app.UIFigure, 'You must first accept or reject the outliers before proceeding with manual screening.', 'Outlier Acceptance Required');
                return;
            end
            
            % Get the brushed data from the UIAxes
            % Assuming that 'app.UIAxes_ScreeningData' is used for the plot
            brushedData = get(app.UIAxes_ScreeningData.Children, 'BrushData');
        
            % Convert brushedData to a logical array
            brushedIndices = brushedData == 1;
        
            % Check if there is any data marked for deletion
            if isempty(brushedIndices) || ~any(brushedIndices)
                % If no data is brushed, show a warning message
                uialert(app.UIFigure, 'No points marked as outliers. Please use the brush tool to mark data points.', 'No Outliers Marked');
                return;
            end
        
            % Get the X and Y data from the screeningData
            xData = screeningData{:, 1};
            yData = screeningData{:, 2};
        
            % Remove the brushed points from the data
            xDataCleaned = xData(~brushedIndices);
            yDataCleaned = yData(~brushedIndices);
        
            % Create cleaned data tables for X and Y
            modifiedXLabel = app.XAxisDropDown.Value;
            modifiedXLabel = strrep(modifiedXLabel, '_', ' ');
        
            modifiedYLabel = app.YAxisDropDown.Value;
            modifiedYLabel = strrep(modifiedYLabel, '_', ' ');
        
            xColumnTable = table(xDataCleaned, 'VariableNames', {modifiedXLabel});
            yColumnTable = table(yDataCleaned, 'VariableNames', {modifiedYLabel});
        
            % Update the screening data table with the cleaned data
            updatedScreeningData = [xColumnTable, yColumnTable];
            app.table_screeningData.Data = updatedScreeningData;
        
            % Update the plot with the cleaned data
            scatter(app.UIAxes_ScreeningData, xDataCleaned, yDataCleaned);
        
            % Label the axes again after updating the plot
            xlabel(app.UIAxes_ScreeningData, modifiedXLabel);
            ylabel(app.UIAxes_ScreeningData, modifiedYLabel);
            title(app.UIAxes_ScreeningData, sprintf('Plot of %s vs %s', modifiedYLabel, modifiedXLabel));

        end

        % Button pushed function: CompleteManualScreeningButton
        function CompleteManualScreeningButtonPushed(app, event)
            % Set the selected tab in the TabGroup to HomeTab
            app.ScreeningProcessTab.SelectedTab = app.HomeTab;          
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)

    % Get the value selected in the ComponentDropDown (file name)
    selectedFile = app.ComponentDropDown.Value;

    % Define the folder path where the files are stored
    if ispc
        userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
    else
        userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
    end

    % Get the selected data type from the DataTypeDropDown
    selectedDataType = app.DataTypeDropDown.Value;

    % Define the main data folder and the screened data subfolder
    dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', selectedDataType);
    screenedDataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', 'Screened_Experimental_Data');

    % Create the folders if they do not exist
    if ~exist(screenedDataFolder, 'dir')
        mkdir(screenedDataFolder);
    end

    % Create the full path to the selected file
    fullFilePath = fullfile(dataFolder, selectedFile);
    
    % Create a new file name with '_screened' suffix if it doesn't already exist
    [~, name, ext] = fileparts(selectedFile);
    if endsWith(name, '_screened')
        screenedFileName = strcat(name, ext);
    else
        screenedFileName = strcat(name, '_screened', ext);
    end

    screenedFilePath = fullfile(screenedDataFolder, screenedFileName);

    % Check if the file exists
    if ~exist(screenedFilePath, 'file')
        % Read the original data from the unscreened CSV file
        opts = detectImportOptions(fullFilePath);
        originalData = readtable(fullFilePath, opts);
    
    else

    % Read the original data from the screened CSV file
    opts = detectImportOptions(screenedFilePath);
    originalData = readtable(screenedFilePath, opts);
    
    end

    % Get the current data from the screening data table
    screenedData = app.table_screeningData.Data;

    % Extract column names from dropdowns for X and Y
    selectedXColumn = app.XAxisDropDown.Value;
    selectedYColumn = app.YAxisDropDown.Value;

    % Find the index of the selected columns in the original data table
    xColumnIdx = find(strcmp(originalData.Properties.VariableNames, selectedXColumn));
    yColumnIdx = find(strcmp(originalData.Properties.VariableNames, selectedYColumn));

    % Check if columns exist in the original data
    if isempty(xColumnIdx) || isempty(yColumnIdx)
        uialert(app.UIFigure, 'Could not find the specified columns in the original file.', 'Column Error');
        return;
    end

    % Update only the specified columns with the screened data
    % Fill with NaN for any removed values to maintain size
    numOriginalRows = size(originalData, 1);
    numScreenedRows = size(screenedData, 1);

    % Initialize columns with NaN values
    updatedXColumnData = NaN(numOriginalRows, 1);
    updatedYColumnData = NaN(numOriginalRows, 1);

    % Copy the screened data to the appropriate rows
    updatedXColumnData(1:numScreenedRows) = table2array(screenedData(:, 1)); % X-axis data from screenedData
    updatedYColumnData(1:numScreenedRows) = table2array(screenedData(:, 2)); % Y-axis data from screenedData

    % Update the original data table with the screened data for the specified columns
    originalData{:, xColumnIdx} = updatedXColumnData;
    originalData{:, yColumnIdx} = updatedYColumnData;


    % Write the updated data to the new CSV file in the screened data folder
    writetable(originalData, screenedFilePath);

    % Display success message to user
    uialert(app.UIFigure, 'Screened data saved successfully in the Screened_Experimental_Data folder!', 'Save Success');

    % Re-plot the data to reflect the latest changes
    % PlotDataButtonPushed(app, event);


        end

        % Drop down opening function: DataTypeDropDown
        function DataTypeDropDownOpening(app, event)

            % Define the folder path for TTP_GUI_Data
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data');
        
            % Check if the TTP_GUI_Data folder exists
            if ~exist(dataFolder, 'dir')
                % If the folder does not exist, show an alert
                uialert(app.UIFigure, 'No data folder found. Please create or import data to proceed.', 'Warning: No Data Found');
                % Set the dropdown to default value since no folder is found
                app.DataTypeDropDown.Items = {'No Data Available'};
                return;
            end
        
            % Get the list of subfolders within the TTP_GUI_Data folder
            folderInfo = dir(dataFolder);
            isSubFolder = [folderInfo.isdir] & ~ismember({folderInfo.name}, {'.', '..'});
            subFolders = {folderInfo(isSubFolder).name};
        
            % Update the DataTypeDropDown items with the folder names
            if isempty(subFolders)
                % If there are no subfolders, show an alert and provide a default option
                uialert(app.UIFigure, 'No data folders found within TTP_GUI_Data. Please add data to proceed.', 'Warning: No Data Found');
                app.DataTypeDropDown.Items = {'No Data Available'};
            else
                % Use the folder names as items in the dropdown
                app.DataTypeDropDown.Items = subFolders;
            end

        end

        % Button pushed function: ToComponentManagerButton
        function ToComponentManagerButtonPushed(app, event)
            % Set the selected tab in the TabGroup to ScreeningTab
            app.TabGroup.SelectedTab = app.ComponentManagerTab;
        end

        % Button pushed function: AutoViaRegressionTreesButton
        function AutoViaRegressionTreesButtonPushed(app, event)
            % Set the selected tab in the TabGroup to ScreeningTab
            app.ScreeningProcessTab.SelectedTab = app.AutoTab;
        end

        % Button pushed function: StartAutoScreeningButton
        function StartAutoScreeningButtonPushed(app, event)
        
            % Extract the name from ComponentDropDown (text before the first underscore or dash)
            fullComponentName = app.ComponentDropDown.Value;
            splitName = regexp(fullComponentName, '[_-]', 'split');
            name = splitName{1};
        
            % Extract variable names for x-axis and y-axis from dropdowns
            xAxisName = app.XAxisDropDown.Value;
            yAxisName = app.YAxisDropDown.Value;
            var_names = {xAxisName, yAxisName}; % Row vector containing names for x and y
        
            % Set tau and other parameters
            tau = [0.25, 0.5, 0.75]; % Represents the 25th, 50th (median), and 75th quartiles needed for the regression
            NumTrees = app.NumberofTreesEditField.Value; 
            binSize = app.BinningSizeEditField.Value; 
            k = app.MultiplierfortheIQRFenceEditField.Value;
            plotAll = false; % Set plotAll to false
            Tbl = app.table_screeningData.Data;
        
            % Construct the dynamic file path using genpath to add all subfolders
            appFolderPath = fileparts(mfilename('fullpath'));
            addpath(genpath(appFolderPath));
        
            [screenedData,detectedOutlines,limits] = removeOutliers( Tbl, NumTrees, tau, binSize, k, plotAll, name, var_names);
        
        
            % Clear existing plot
            cla(app.UIAxes_ScreeningData);
            hold(app.UIAxes_ScreeningData, 'on');
        
            % Plot screened data as blue points
            plot(app.UIAxes_ScreeningData, screenedData{:, 1}, screenedData{:, 2}, Marker = "o", MarkerSize=8, LineStyle = "none",Color = "#88CCEE",LineWidth=1);
            
        
            % Plot detected outliers as red asterisks
            if ~isempty(detectedOutlines)
                plot(app.UIAxes_ScreeningData, detectedOutlines{:, 1}, detectedOutlines{:, 2}, Marker = "*", MarkerSize=8, LineStyle = "none",Color = "#882255",LineWidth=1);
            end 
        
            % Plot limits as lines (using temperature as the x-axis)
            if ~isempty(limits)
                plot(app.UIAxes_ScreeningData, limits{:, 1}, limits{:, 2}, Marker = "none", MarkerSize=8, LineStyle = "--",Color = "#44AA99",LineWidth=1.5); % Lower limit
                plot(app.UIAxes_ScreeningData, limits{:, 1}, limits{:, 3}, Marker = "none", MarkerSize=8, LineStyle = ":" ,Color = "#332288",LineWidth=1.5); % Upper limit
            end
        
            % Modify the axis labels
            xlabel(app.UIAxes_ScreeningData, strrep(xAxisName, '_', ' '));
            ylabel(app.UIAxes_ScreeningData, strrep(yAxisName, '_', ' '));
            title(app.UIAxes_ScreeningData, 'Screening Data with Detected Outliers');
        
            hold(app.UIAxes_ScreeningData, 'off');

            % Update the table_screeningData with screenedData
            app.table_screeningData.Data = screenedData;

        end

        % Button pushed function: AcceptOutliersButton
        function AcceptOutliersButtonPushed(app, event)
            %This function takes the current screen data and stores it within 
            % the table in such a way that the currently screened data is treated 
            % as the raw data so that we can either screen the data again
            % or move on to save.
            
            % Setting the original data set (or last Screened data set) to be the new data source
            newSourceData = app.table_screeningData.Data; 

            % Plot Accepted data points as blue points
            plot(app.UIAxes_ScreeningData, newSourceData{:, 1}, newSourceData{:, 2}, Marker = "o", MarkerSize=8, LineStyle = "none",Color = "#88CCEE",LineWidth=1);
            
            % Storing the original data set (or last Screened data set) to be the new data source
            app.table_sourceData.Data = newSourceData;
        end

        % Button pushed function: RejectOutliersButton
        function RejectOutliersButtonPushed(app, event)
            %This function effectively restores the plots and data to the 
            % prior setof accepted data or the original set of data in 
            % the event that no data has yet to be accepted
            
            % Setting the original data set (or last source data set) to be the new data source
            oldSourceData = app.table_sourceData.Data; 

            % Setting the original data set (or last source data set) to be the new screened data 
            app.table_screeningData.Data = oldSourceData;

             % Plot the original data set (or last source data set)  points as blue points
            plot(app.UIAxes_ScreeningData, oldSourceData{:, 1}, oldSourceData{:, 2}, Marker = "o", MarkerSize=8, LineStyle = "none",Color = "#88CCEE",LineWidth=1);

        end

        % Button pushed function: CompleteScreeningButton
        function CompleteScreeningButtonPushed(app, event)
            % Set the selected tab in the TabGroup to HomeTab
            app.ScreeningProcessTab.SelectedTab = app.HomeTab; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ComponentManagerTab = uitab(app.TabGroup);
            app.ComponentManagerTab.Title = 'Component Manager';
            app.dataType = uitabgroup(app.ComponentManagerTab);
            app.RequiredTab = uitab(app.dataType);
            app.RequiredTab.Title = 'Required';
            app.dataDisplayTable = uitable(app.RequiredTab);
            app.dataDisplayTable.ColumnName = {'Component'; 'CAS'; 'criticalTemperature_K'; 'criticalPressure_kPa'; 'saturation_temperature_K'; 'saturation_pressure_kPa'; 'enthalpy_vaporization_temperature_K'; 'enthalpy_vaporization_kJ_per_mol'; 'cp_temperature_K'; 'cp_J_per_K_mol'};
            app.dataDisplayTable.ColumnRearrangeable = 'on';
            app.dataDisplayTable.RowName = {};
            app.dataDisplayTable.ColumnEditable = true;
            app.CASisusedAsauniqueidentifiertolinkdatafromLabel = uilabel(app.RequiredTab);
            app.CASisusedAsauniqueidentifiertolinkdatafromLabel.Text = 'CAS number is essential for linking and cross-referencing data between different tables.';
            app.AllinthissectionisusedforLabel = uilabel(app.RequiredTab);
            app.AllinthissectionisusedforLabel.Text = 'All data in this section is used for the optimization of alpha parameters, excluding data will require modification of optimization process. ';
            app.VariableSelectorButtonGroup = uibuttongroup(app.RequiredTab);
            app.VariableSelectorButtonGroup.Title = 'Variable Selector';
            app.criticalTemperature_KButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.criticalTemperature_KButton.Text = 'criticalTemperature_K';
            app.criticalTemperature_KButton.Value = true;
            app.criticalPressure_kPaButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.criticalPressure_kPaButton.Text = 'criticalPressure_kPa';
            app.saturation_temperature_KButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.saturation_temperature_KButton.Text = 'saturation_temperature_K';
            app.saturation_pressure_kPaButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.saturation_pressure_kPaButton.Text = 'saturation_pressure_kPa';
            app.enthalpy_vaporization_temperature_KButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.enthalpy_vaporization_temperature_KButton.Text = 'enthalpy_vaporization_temperature_K';
            app.enthalpy_vaporization_kJ_per_molButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.enthalpy_vaporization_kJ_per_molButton.Text = 'enthalpy_vaporization_kJ_per_mol';
            app.cp_temperature_KButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.cp_temperature_KButton.Text = 'cp_temperature_K';
            app.cp_J_per_K_molButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.cp_J_per_K_molButton.Text = 'cp_J_per_K_mol';
            app.ComponentButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.ComponentButton.Text = 'Component';
            app.CASButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.CASButton.Text = 'CAS';
            app.ManualDataEntryWindowTextAreaLabel = uilabel(app.RequiredTab);
            app.ManualDataEntryWindowTextAreaLabel.Text = 'Manual Data Entry Window';
            app.ManualDataEntryWindowTextArea = uitextarea(app.RequiredTab);
            app.AddDataButton = uibutton(app.RequiredTab, 'push');
            app.AddDataButton.ButtonPushedFcn = createCallbackFcn(app, @AddDataButtonPushed, true);
            app.AddDataButton.Text = 'Add Data';
            app.SaveTableButton = uibutton(app.RequiredTab, 'push');
            app.SaveTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableButtonPushed, true);
            app.SaveTableButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'save2_img.png');
            app.SaveTableButton.Text = 'Save Table';
            app.ClearTableButton = uibutton(app.RequiredTab, 'push');
            app.ClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTableButtonPushed, true);
            app.ClearTableButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'clear_img.png');
            app.ClearTableButton.Text = 'Clear Table';
            app.ImportDataButton = uibutton(app.RequiredTab, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'import_image.png');
            app.ImportDataButton.Text = 'Import Data     ';
            app.CASisusedAsauniqueidentifiertolinkdatafromLabel_2 = uilabel(app.RequiredTab);
            app.CASisusedAsauniqueidentifiertolinkdatafromLabel_2.Text = 'unsaved files will be lost!';
            app.ToDataScreeningButton = uibutton(app.RequiredTab, 'push');
            app.ToDataScreeningButton.ButtonPushedFcn = createCallbackFcn(app, @ToDataScreeningButtonPushed, true);
            app.ToDataScreeningButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'screening_image.png');
            app.ToDataScreeningButton.Text = 'To Data Screening';
            app.Image = uiimage(app.RequiredTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'GUI_files', 'image', 'arrowR_image.png');
            app.DataScreeningTab = uitab(app.TabGroup);
            app.DataScreeningTab.Title = 'Data Screening';
            app.UIAxes_ScreeningData = uiaxes(app.DataScreeningTab);
            title(app.UIAxes_ScreeningData, 'Title')
            xlabel(app.UIAxes_ScreeningData, 'X')
            ylabel(app.UIAxes_ScreeningData, 'Y')
            zlabel(app.UIAxes_ScreeningData, 'Z')
            app.PlotAxisPanel = uipanel(app.DataScreeningTab);
            app.PlotAxisPanel.Title = 'Plot Axis';
            app.XAxisLabel = uilabel(app.PlotAxisPanel);
            app.XAxisLabel.Text = 'X-Axis :';
            app.XAxisDropDown = uidropdown(app.PlotAxisPanel);
            app.XAxisDropDown.Items = {'Select X-Axis Variable'};
            app.XAxisDropDown.DropDownOpeningFcn = createCallbackFcn(app, @XAxisDropDownOpening, true);
            app.XAxisDropDown.Value = 'Select X-Axis Variable';
            app.YAxisLabel = uilabel(app.PlotAxisPanel);
            app.YAxisLabel.Text = 'Y-Axis :';
            app.YAxisDropDown = uidropdown(app.PlotAxisPanel);
            app.YAxisDropDown.Items = {'Select Y-Axis Variable'};
            app.YAxisDropDown.DropDownOpeningFcn = createCallbackFcn(app, @YAxisDropDownOpening, true);
            app.YAxisDropDown.Value = 'Select Y-Axis Variable';
            app.PlotDataButton = uibutton(app.PlotAxisPanel, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data';
            app.ComponentLabel = uilabel(app.PlotAxisPanel);
            app.ComponentLabel.Text = 'Component :';
            app.ComponentDropDown = uidropdown(app.PlotAxisPanel);
            app.ComponentDropDown.Items = {'Select File'};
            app.ComponentDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ComponentDropDownOpening, true);
            app.ComponentDropDown.Value = 'Select File';
            app.DataTypeDropDownLabel = uilabel(app.PlotAxisPanel);
            app.DataTypeDropDownLabel.Text = 'Data Type :';
            app.DataTypeDropDown = uidropdown(app.PlotAxisPanel);
            app.DataTypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DataTypeDropDownOpening, true);
            app.ToComponentManagerButton = uibutton(app.DataScreeningTab, 'push');
            app.ToComponentManagerButton.ButtonPushedFcn = createCallbackFcn(app, @ToComponentManagerButtonPushed, true);
            app.ToComponentManagerButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'tableBig_img.png');
            app.ToComponentManagerButton.Text = {'To Component '; 'Manager'};
            app.ScreeningProcessTab = uitabgroup(app.DataScreeningTab);
            app.HomeTab = uitab(app.ScreeningProcessTab);
            app.HomeTab.Title = 'Home';
            app.SaveDataButton = uibutton(app.HomeTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'save2_img.png');
            app.SaveDataButton.Text = 'Save Data';
            app.StartScreeningButton = uibutton(app.HomeTab, 'push');
            app.StartScreeningButton.ButtonPushedFcn = createCallbackFcn(app, @StartScreeningButtonPushed, true);
            app.StartScreeningButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'screening_image.png');
            app.StartScreeningButton.Text = 'Start Screening  ';
            app.ScreeningTypeTab = uitab(app.ScreeningProcessTab);
            app.ScreeningTypeTab.Title = 'Screening  Type';
            app.ManualButton = uibutton(app.ScreeningTypeTab, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Text = 'Manual';
            app.AutoViaRegressionTreesButton = uibutton(app.ScreeningTypeTab, 'push');
            app.AutoViaRegressionTreesButton.ButtonPushedFcn = createCallbackFcn(app, @AutoViaRegressionTreesButtonPushed, true);
            app.AutoViaRegressionTreesButton.Text = {'Auto  '; '( Via Regression Trees )'};
            app.ManualTab = uitab(app.ScreeningProcessTab);
            app.ManualTab.Title = 'Manual';
            app.RemoveMarkedOutliersButton = uibutton(app.ManualTab, 'push');
            app.RemoveMarkedOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveMarkedOutliersButtonPushed, true);
            app.RemoveMarkedOutliersButton.Text = {'Remove '; 'Marked Outliers'};
            app.CompleteManualScreeningButton = uibutton(app.ManualTab, 'push');
            app.CompleteManualScreeningButton.ButtonPushedFcn = createCallbackFcn(app, @CompleteManualScreeningButtonPushed, true);
            app.CompleteManualScreeningButton.Text = {'Complete Manual'; 'Screening'};
            app.UsetheplotbrushtooltomarkupdataLabel = uilabel(app.ManualTab);
            app.UsetheplotbrushtooltomarkupdataLabel.Text = 'Use the plot brush tool to mark up data.';
            app.AutoTab = uitab(app.ScreeningProcessTab);
            app.AutoTab.Title = 'Auto  ';
            app.StartAutoScreeningButton = uibutton(app.AutoTab, 'push');
            app.StartAutoScreeningButton.ButtonPushedFcn = createCallbackFcn(app, @StartAutoScreeningButtonPushed, true);
            app.StartAutoScreeningButton.Text = 'Start Auto Screening';
            app.AcceptOutliersButton = uibutton(app.AutoTab, 'push');
            app.AcceptOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptOutliersButtonPushed, true);
            app.AcceptOutliersButton.Text = 'Accept Outliers';
            app.RejectOutliersButton = uibutton(app.AutoTab, 'push');
            app.RejectOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @RejectOutliersButtonPushed, true);
            app.RejectOutliersButton.Text = 'Reject Outliers';
            app.CompleteScreeningButton = uibutton(app.AutoTab, 'push');
            app.CompleteScreeningButton.ButtonPushedFcn = createCallbackFcn(app, @CompleteScreeningButtonPushed, true);
            app.CompleteScreeningButton.Text = 'Complete Screening';
            app.InputParametersPanel = uipanel(app.AutoTab);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.BinningSizeLabel = uilabel(app.InputParametersPanel);
            app.BinningSizeLabel.Text = 'Binning Size :';
            app.BinningSizeEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.BinningSizeEditField.ValueDisplayFormat = '%.0f';
            app.BinningSizeEditField.Value = 50;
            app.MultiplierfortheIQRFenceLabel = uilabel(app.InputParametersPanel);
            app.MultiplierfortheIQRFenceLabel.Text = 'Multiplier for the IQR Fence :';
            app.MultiplierfortheIQRFenceEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.MultiplierfortheIQRFenceEditField.ValueDisplayFormat = '%.1f';
            app.MultiplierfortheIQRFenceEditField.Value = 1.5;
            app.NumberofTreesEditFieldLabel = uilabel(app.InputParametersPanel);
            app.NumberofTreesEditFieldLabel.Text = 'Number of Trees :';
            app.NumberofTreesEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.NumberofTreesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofTreesEditField.Value = 100;
            app.table_screeningData = uitable(app.DataScreeningTab);
            app.table_screeningData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.table_screeningData.RowName = {};
            app.table_sourceData = uitable(app.DataScreeningTab);
            app.table_sourceData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.table_sourceData.RowName = {};
            app.SourceDataLabel = uilabel(app.DataScreeningTab);
            app.SourceDataLabel.Text = 'Source Data';
            app.ScreenedDataLabel = uilabel(app.DataScreeningTab);
            app.ScreenedDataLabel.Text = 'Screened Data';
            app.OptimizerTab = uitab(app.TabGroup);
            app.OptimizerTab.Title = 'Optimizer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TTP_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end